classdef movable_port_arduDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PortButton                      matlab.ui.control.Button
        TTLstatusLamp                   matlab.ui.control.Lamp
        TTLstatusLampLabel              matlab.ui.control.Label
        DAQARDUListBox                  matlab.ui.control.ListBox
        ARDUDAQLabel                    matlab.ui.control.Label
        ARDUdaqConnectButton            matlab.ui.control.Button
        repSwitch                       matlab.ui.control.Label
        RepetitionLabel                 matlab.ui.control.Label
        DAQTab                          matlab.ui.container.TabGroup
        taskparamTab                    matlab.ui.container.Tab
        TotaltrialNumEditField          matlab.ui.control.EditField
        TotaltrialNumEditFieldLabel     matlab.ui.control.Label
        CCAirpuffProb                   matlab.ui.control.EditField
        AirpuffprobLabel                matlab.ui.control.Label
        GNGTimeoutsEditField            matlab.ui.control.EditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        GONOGOLabel                     matlab.ui.control.Label
        GNGReinforcerdelaysEditField    matlab.ui.control.EditField
        ReinforcerdelaysEditFieldLabel  matlab.ui.control.Label
        GNGLickwindowsEditField         matlab.ui.control.EditField
        LickwindowsEditFieldLabel       matlab.ui.control.Label
        GNGPortDelaysEditField          matlab.ui.control.EditField
        PortDelaysEditField_2Label      matlab.ui.control.Label
        GNGRespdurationsEditField       matlab.ui.control.EditField
        RespdurationsEditField_2Label   matlab.ui.control.Label
        GNGStimdurationsEditField       matlab.ui.control.EditField
        StimdurationsEditField_2Label   matlab.ui.control.Label
        GNGISIsEditField                matlab.ui.control.EditField
        ISIsEditField_2Label            matlab.ui.control.Label
        GNGStartLEDdursEditField        matlab.ui.control.EditField
        StartLEDdursEditField_2Label    matlab.ui.control.Label
        airpuffDuration                 matlab.ui.control.EditField
        AirpuffdurationsLabel           matlab.ui.control.Label
        ClassicalConditioningLabel      matlab.ui.control.Label
        CCPortDelaysEditField           matlab.ui.control.EditField
        PortDelaysEditFieldLabel        matlab.ui.control.Label
        ReinforcerProbabilityEditField  matlab.ui.control.EditField
        ReinforcerprobLabel             matlab.ui.control.Label
        WatersizeLEditField             matlab.ui.control.EditField
        WatersizeLEditFieldLabel        matlab.ui.control.Label
        StimFreqkHzEditField            matlab.ui.control.EditField
        StimFreqkHzEditFieldLabel       matlab.ui.control.Label
        CCUSDelaysEditField             matlab.ui.control.EditField
        USDelaysEditFieldLabel          matlab.ui.control.Label
        ITIsEditField                   matlab.ui.control.EditField
        ITIsEditFieldLabel              matlab.ui.control.Label
        CCRespdurationsEditField        matlab.ui.control.EditField
        RespdurationsEditFieldLabel     matlab.ui.control.Label
        CCStimdurationsEditField        matlab.ui.control.EditField
        StimdurationsEditFieldLabel     matlab.ui.control.Label
        CCISIsEditField                 matlab.ui.control.EditField
        ISIsEditFieldLabel              matlab.ui.control.Label
        CCStartLEDdursEditField         matlab.ui.control.EditField
        StartLEDdursEditFieldLabel      matlab.ui.control.Label
        pinandwatercalibrationTab       matlab.ui.container.Tab
        ControlARDUPinInfoifchangehavetomodifyondeviceLabel  matlab.ui.control.Label
        WatercalibrationLabel           matlab.ui.control.Label
        ofdatapointsEditField           matlab.ui.control.EditField
        ofdatapointsEditFieldLabel      matlab.ui.control.Label
        ValveOpensEditField             matlab.ui.control.EditField
        ValveOpensEditFieldLabel        matlab.ui.control.Label
        RunCalibrationButton            matlab.ui.control.Button
        AifpuffdaqEditField             matlab.ui.control.EditField
        AifpuffdaqEditFieldLabel        matlab.ui.control.Label
        WaterDaqEditField               matlab.ui.control.EditField
        WaterDaqEditFieldLabel          matlab.ui.control.Label
        MotorDaqEditField               matlab.ui.control.EditField
        MotorDaqEditFieldLabel          matlab.ui.control.Label
        LEDDaqEditField                 matlab.ui.control.EditField
        LEDDaqEditFieldLabel            matlab.ui.control.Label
        AirpuffEditField                matlab.ui.control.EditField
        AirpuffEditFieldLabel           matlab.ui.control.Label
        LEDEditField                    matlab.ui.control.EditField
        LEDEditFieldLabel               matlab.ui.control.Label
        MotorEditField                  matlab.ui.control.EditField
        MotorEditFieldLabel             matlab.ui.control.Label
        WaterEditField                  matlab.ui.control.EditField
        WaterEditFieldLabel             matlab.ui.control.Label
        LickSensorInputEditField        matlab.ui.control.EditField
        LickSensorInputEditFieldLabel   matlab.ui.control.Label
        SoundDaqEditField               matlab.ui.control.EditField
        SoundDaqEditFieldLabel          matlab.ui.control.Label
        ARDUDaqInfoTab                  matlab.ui.container.Tab
        ARDUdaqSwtich                   matlab.ui.control.Switch
        EditField_20                    matlab.ui.control.EditField
        Label_10                        matlab.ui.control.Label
        EditField_19                    matlab.ui.control.EditField
        Label_9                         matlab.ui.control.Label
        EditField_18                    matlab.ui.control.EditField
        Label_12                        matlab.ui.control.Label
        EditField_17                    matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        EditField_16                    matlab.ui.control.EditField
        EditField_16Label               matlab.ui.control.Label
        EditField_15                    matlab.ui.control.EditField
        EditField_15Label               matlab.ui.control.Label
        EditField_14                    matlab.ui.control.EditField
        EditField_14Label               matlab.ui.control.Label
        EditField_13                    matlab.ui.control.EditField
        EditField_13Label               matlab.ui.control.Label
        EditField_12                    matlab.ui.control.EditField
        EditField_12Label               matlab.ui.control.Label
        EditField_11                    matlab.ui.control.EditField
        EditField_11Label               matlab.ui.control.Label
        EditField_9                     matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        BaudRateHzEditField             matlab.ui.control.EditField
        BaudRateHzEditFieldLabel        matlab.ui.control.Label
        InputChannelsEditField_2        matlab.ui.control.EditField
        InputChannelsEditField_2Label   matlab.ui.control.Label
        ArduinoDigitalIOPINsifchangehavetomodifyondeviceLabel  matlab.ui.control.Label
        AD3DaqTab                       matlab.ui.container.Tab
        AD3DAQListBox                   matlab.ui.control.ListBox
        DAQListBoxLabel                 matlab.ui.control.Label
        ADdaqConnectButton              matlab.ui.control.Button
        AD3daqSwtich                    matlab.ui.control.Switch
        AD3DigitalPINsLabel             matlab.ui.control.Label
        EditField_8                     matlab.ui.control.EditField
        EditField_8Label                matlab.ui.control.Label
        EditField_7                     matlab.ui.control.EditField
        EditField_7Label                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.EditField
        EditField_6Label                matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        EditField_5Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        RecordingTTLLamp                matlab.ui.control.Lamp
        RecordingTTLLampLabel           matlab.ui.control.Label
        RangeVEditField                 matlab.ui.control.EditField
        RangeVLabel                     matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.EditField
        SampRateHzLabel                 matlab.ui.control.Label
        InputChannelsEditField          matlab.ui.control.EditField
        InputChannelsEditFieldLabel     matlab.ui.control.Label
        NIDaqTab                        matlab.ui.container.Tab
        NIDAQListBox                    matlab.ui.control.ListBox
        DAQListBox_3Label               matlab.ui.control.Label
        AI7EditField                    matlab.ui.control.EditField
        AI7EditFieldLabel               matlab.ui.control.Label
        AI6EditField                    matlab.ui.control.EditField
        AI6EditFieldLabel               matlab.ui.control.Label
        AI5EditField                    matlab.ui.control.EditField
        AI5EditFieldLabel               matlab.ui.control.Label
        AI4EditField                    matlab.ui.control.EditField
        AI4EditFieldLabel               matlab.ui.control.Label
        AI3EditField                    matlab.ui.control.EditField
        AI3EditFieldLabel               matlab.ui.control.Label
        AI2EditField                    matlab.ui.control.EditField
        AI2EditFieldLabel               matlab.ui.control.Label
        AI1EditField                    matlab.ui.control.EditField
        AI1EditFieldLabel               matlab.ui.control.Label
        AI0EditField                    matlab.ui.control.EditField
        AI0EditFieldLabel               matlab.ui.control.Label
        RecordingTTLLamp_3              matlab.ui.control.Lamp
        RecordingTTLLamp_3Label         matlab.ui.control.Label
        RangeVEditField_3               matlab.ui.control.EditField
        RangeVEditField_3Label          matlab.ui.control.Label
        SampleRateHzEditField_3         matlab.ui.control.EditField
        SampleRateHzEditField_3Label    matlab.ui.control.Label
        InputChannelsEditField_3        matlab.ui.control.EditField
        InputChannelsEditField_3Label   matlab.ui.control.Label
        NIdaqConnectButton              matlab.ui.control.Button
        NIdaqSwtich                     matlab.ui.control.Switch
        NIDAQPINsLabel                  matlab.ui.control.Label
        ReSelectsavingfolderButton      matlab.ui.control.Button
        TaskstoppedLamp                 matlab.ui.control.Lamp
        TaskstoppedLampLabel            matlab.ui.control.Label
        RunSwitch                       matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        sessioninfoTab                  matlab.ui.container.Tab
        MotorconnectedCheckBox          matlab.ui.control.CheckBox
        MotorDirectionSwitch            matlab.ui.control.Switch
        MotorDirectionSwitchLabel       matlab.ui.control.Label
        RigEditField                    matlab.ui.control.EditField
        RigEditFieldLabel               matlab.ui.control.Label
        AnimaltypeEditField             matlab.ui.control.EditField
        AnimaltypeEditFieldLabel        matlab.ui.control.Label
        ExptypeEditField                matlab.ui.control.EditField
        ExptypeEditFieldLabel           matlab.ui.control.Label
        AnimalIDEditField               matlab.ui.control.EditField
        AnimalIDEditFieldLabel          matlab.ui.control.Label
        initTaskTypeSwitch              matlab.ui.control.Switch
        ExperimenterEditFieldLabel      matlab.ui.control.Label
        ExperimenterEditField           matlab.ui.control.EditField
        TaskListBox                     matlab.ui.control.ListBox
        TaskListBoxLabel                matlab.ui.control.Label
        ControlARDUListBox              matlab.ui.control.ListBox
        ControlARDUListBoxLabel         matlab.ui.control.Label
        RevLearningV20ClassicalConditionMadeforAnalogDiscovery3Label  matlab.ui.control.Label
        DurationEditField               matlab.ui.control.EditField
        DurationEditFieldLabel          matlab.ui.control.Label
        StopEditField                   matlab.ui.control.EditField
        StopEditFieldLabel              matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        BPlickRate                      matlab.ui.control.Label
        DprimeLabel                     matlab.ui.control.Label
        status                          matlab.ui.control.Label
        StateLabel                      matlab.ui.control.Label
        BRlickRate                      matlab.ui.control.Label
        AccuracyLabel                   matlab.ui.control.Label
        NlickRate                       matlab.ui.control.Label
        NCSlicksLabel                   matlab.ui.control.Label
        RlickRate                       matlab.ui.control.Label
        RCSlicksLabel                   matlab.ui.control.Label
        trialNum                        matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
        AirpuffButton                   matlab.ui.control.Button
        WaterButton                     matlab.ui.control.Button
        ConnectButton_4                 matlab.ui.control.Button
        bigPlot                         matlab.ui.control.UIAxes
        smallPlot                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Info % Description
        CC
        GNG
        DAQ
        CalibrationParam
        ADInput
        Stim
    end
    
    methods (Access = private)
        %% Water calibration: release  water + get weight differences
        function [diff_weight] = calibration(app)
            opening_durs = app.CalibrationParam.calibTime;
            diff_weight = nan(1, length(opening_durs));

            for idx = 1:length(opening_durs) % usually 3
                % generate text for input and get the inputed data
                initial_weight = WeightInputWindow(app,'Initial');
                % Displaying the process in the log text area
                app.LogTextArea.Value{end+1} = ...
                    ['calibrating the port by ',num2str(app.CalibrationParam.calibTime(idx)),' sec'];
                scroll(app.LogTextArea, 'bottom');
                pause(3);

                % water dispense
                waterRelease(app, idx);

                % generate text for input and get the inputed data
                after_weight = WeightInputWindow(app,'Final');
                diff_weight(idx) = after_weight - initial_weight;
            end
        end

        function waterRelease(app, idx) % removed the side info from function
            nRelease = app.CalibrationParam.nRelease;
            for eachOpening = 1:nRelease
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 1); 
                % wait for water to be dispensed
                pause(app.CalibrationParam.calibTime(idx));
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 0);
                pause(0.5);
            end
        end
        
        %% Weight input 
        function [weight] = WeightInputWindow(app, prompt) % get calibration data from input windows
            prompt_full = {[prompt, 'Weight [g]']};
            dlgtitle = 'Please Input'; %title of the dialog box
            fieldsize = [1 35]; % size of the input field
            definput = {''};
            weight_input = inputdlg(prompt_full, dlgtitle, fieldsize, definput); % generate an input dialog box
            % answer = inputdlg(prompt,dlgtitle,fieldsize,definput
            weight = str2double(weight_input{1}); % get the inputed data            
        end
        
        %% Draw weights + save fitted slope to app.CalibrationParam.slope
        function drawWeights(app, diff_weight) 
            nRelease = app.CalibrationParam.nRelease;
            opening_durs = app.CalibrationParam.calibTime;
            ave_weight = diff_weight/nRelease; % same length as opening_durs

            figure();            
            scatter(opening_durs, ave_weight, 'Marker','o','MarkerEdgeColor','#336B87');
            model = fitglm([opening_durs 0]',[ave_weight 0]','Intercept',false);
            slope = model.Coefficients.Estimate;
            app.CalibrationParam.slope = slope;
            yhat = slope*(max(opening_durs)+0.05);
            hold on;
            plot([0 max(opening_durs)+0.05],[0 yhat]);
            xlabel('Solenoid open time [s]');
            ylabel('Water amount [ml]');
            title('Water Solenoid Calibration');
            hold off;
        end
        %% Calculated TTL based on reward size and slope and save TTL
        function saveWaterTTLTimeInfo(app, diff_weight)
            slope = app.CalibrationParam.slope; % assigning app.CalibrationParam.slope
            rewardSize = app.CC.taskparam.waterSize;
            openTime = rewardSize / slope;
            app.Info.taskparam.waterTTLTime = openTime/1000;

            xlsxpath = app.Info.path.waterCalibrationRecord;
            sheet = app.Info.session.rig;
            
            % Read the existing data from the sheet
            existingData = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
            fields = existingData.Properties.VariableNames;
       
            % Values for the new row
            time = string(datetime('now', 'Format', 'yyyy-MM-dd HH:mm'));

            valveOpenTime = num2str(app.CalibrationParam.calibTime);
            nRelease = app.CalibrationParam.nRelease;
        
            % Create a table for the new values with the correct side fields
            newRow = table(time, {num2str(diff_weight)}, slope, openTime, ...
                                 rewardSize, {valveOpenTime}, nRelease, ...
                                 'VariableNames', fields);
            % Date|Weight Differences (g)|Fitted Slope (ml/s)|Open Time Used (s)|Reward Size (ml)|Valve Open Time (s)|nRelease														
            
            % Concatenate the new row to the existing data
            updatedData = [existingData; newRow];
        
            % Write back to the Excel sheet
            writetable(updatedData, xlsxpath, 'Sheet', sheet, 'WriteMode', 'overwritesheet');
        end

        function loadWaterTTL(app)
            xlsxpath = app.Info.path.waterCalibrationRecord;
            sheet = app.Info.session.rig;

            % Read the data from the Excel file
            data = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
            
            % Find the column index for the field name "Fitted Slope (ml/s)"
            fields = data.Properties.VariableNames;
            slopeColIdx = strcmp(fields, 'Fitted Slope (ml/s)');
            try
                slope = data{end, slopeColIdx};
                app.CalibrationParam.slope = slope;
                app.CC.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
                app.GNG.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
                app.LogTextArea.Value{end+1} = 'Water TTL successfully updated.';
                scroll(app.LogTextArea, 'bottom');
            catch ME
                confirm = msgbox('No valid slope data found. Please recalibrate.', 'Warning');
                uiwait(confirm);
                app.LogTextArea.Value{end+1} = 'No record found. You have to CALIBRATE!';
                scroll(app.LogTextArea, 'bottom');
            end
        end

        function loadAvailableSerialports(app, boxName, availableCOM)
            % display available serial ports for both ARDU and daq ARDU
            app.(boxName).Items = availableCOM;
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            pause(0.5);

            selected_path = 0 ;
            while selected_path == 0
                selected_path = uigetdir('C:\', 'Please select the project folder!');
            end

            app.Info.path.mother = selected_path;
            app.Info.path.task = fullfile(app.Info.path.mother,'+task');
            task = dir(app.Info.path.task); %%%%% where the task folder is defined
            task = task(contains({task.name}, {'.m'}));
            task = {task.name}; 

            for i = 1:length(task)
                task{i}(end-1:end) = [];
            end

            %% Display task options 
            app.TaskListBox.Items = task;

            %% Display available serial ports for both ARDU and daq ARDU
            availableCOM = serialportlist("available");
            loadAvailableSerialports(app, 'ControlARDUListBox', availableCOM);
            app.Info.ARDU_COM = availableCOM(1);
            loadAvailableSerialports(app, 'DAQARDUListBox', availableCOM);
            app.DAQ.ARDU_COM = availableCOM(1);

            %% DATE update
            % datetime.setDefaultFormats('default','yyyy-MM-dd hh:mm:ss')
            appStartTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            tmp = char(appStartTime);
            app.LogTextArea.Value{end+1} =  ['Opened GUI at ',tmp];
            scroll(app.LogTextArea, 'bottom');
          
            %% Task param setting 
            % CC task parameter setting
            app.CC.taskparam.startCueDur = str2double(app.CCStartLEDdursEditField.Value);
            app.CC.taskparam.isi = str2double(app.CCISIsEditField.Value);
            app.CC.taskparam.stimDur = str2double(app.CCStimdurationsEditField.Value);
            app.CC.taskparam.portDelay = str2double(app.CCPortDelaysEditField.Value);
            app.CC.taskparam.usDelay = str2double(app.CCUSDelaysEditField.Value);
            app.CC.taskparam.respDur = str2double(app.CCRespdurationsEditField.Value);
            app.CC.taskparam.USprobability = str2double(app.ReinforcerProbabilityEditField.Value);
            app.CC.taskparam.airpuffProbability = str2double(app.CCAirpuffProb.Value);

            app.CC.taskparam.waterSize = str2double(app.WatersizeLEditField.Value);
            app.CC.taskparam.iti = str2num(app.ITIsEditField.Value);
            app.CC.taskparam.stimFreq = str2num(app.StimFreqkHzEditField.Value);
            app.CC.taskparam.airpuffDur = str2double(app.airpuffDuration.Value);
            app.CC.taskparam.waterTTLTime = 'n/a';

            % GNG task parameter setting
            app.GNG.taskparam.startCueDur = str2double(app.GNGStartLEDdursEditField.Value);
            app.GNG.taskparam.isi = str2double(app.GNGISIsEditField.Value);
            app.GNG.taskparam.stimDur = str2double(app.GNGStimdurationsEditField.Value);
            app.GNG.taskparam.portDelay = str2double(app.GNGPortDelaysEditField.Value);
            app.GNG.taskparam.lickWindow = str2double(app.GNGLickwindowsEditField.Value);
            app.GNG.taskparam.reinforcerDelay = str2double(app.GNGReinforcerdelaysEditField.Value);
            app.GNG.taskparam.respDur = str2double(app.GNGRespdurationsEditField.Value);
            app.GNG.taskparam.timeout = str2double(app.GNGTimeoutsEditField.Value);
            app.GNG.taskparam.USprobability = str2double(app.ReinforcerProbabilityEditField.Value);

            app.GNG.taskparam.waterSize = str2double(app.WatersizeLEditField.Value);
            app.GNG.taskparam.iti = str2num(app.ITIsEditField.Value);
            app.GNG.taskparam.stimFreq = str2num(app.StimFreqkHzEditField.Value);
            app.GNG.taskparam.airpuffDur = str2double(app.airpuffDuration.Value);
            app.GNG.taskparam.waterTTLTime = 'n/a';

            %% Experiment params setting
            app.Info.session.computerName = [getenv('username'),'-',computer];
            app.Info.session.date = char(datetime('now','Format','yyyy-MM-dd'));
            app.Info.session.animalID = app.AnimalIDEditField.Value;
            value = app.RigEditField.Value;
            if all(isstrprop(value, 'digit'))
                app.Info.session.rig = ['rig', value];
            else
                app.Info.session.rig = value;
            end
            app.Info.session.experimenter = app.ExperimenterEditField.Value;
            app.Info.session.expType = app.ExptypeEditField.Value;
            app.Info.session.animalType = app.AnimaltypeEditField.Value;
            app.Info.session.rule = app.initTaskTypeSwitch.Value;
            app.Info.session.motorConnection = app.MotorconnectedCheckBox.Value;
            app.Info.session.daqrec = true;
            app.Info.session.name = 'n/a';
            app.Info.session.ID = 'n/a';
            app.Info.session.recording = 'n/a';
            app.Info.session.taskName = app.TaskListBox.Value;
            app.Info.session.prespecifiedTrialNum = str2double(app.TotaltrialNumEditField.Value);

            %% Path setting: app.Info.path..
            app.Info.path.mother = selected_path;
            app.Info.path.task = fullfile(app.Info.path.mother, '+task');
            app.Info.path.waterCalibration = fullfile(app.Info.path.mother,'+waterCalibration');
            app.Info.path.waterCalibrationRecord = fullfile(app.Info.path.waterCalibration, 'waterCalibrationRecordEphys.xlsx');
            app.Info.path.data = fullfile(app.Info.path.mother, 'Data');
            app.Info.path.animalData = fullfile(app.Info.path.mother, 'Data-sortByAnimal');
            app.Info.path.dataSession = 'undefined yet';
            
            %% Pin information setting for control ARDU
            app.Info.PIN.LED = app.LEDEditField.Value;
            app.Info.PIN.LEDDaq = app.LEDDaqEditField.Value;
            app.Info.PIN.soundDaq = app.SoundDaqEditField.Value; 
            app.Info.PIN.motor = app.MotorEditField.Value;
            app.Info.PIN.motorDaq = app.MotorDaqEditField.Value;
            app.Info.PIN.water = app.WaterEditField.Value; 
            app.Info.PIN.waterDaq = app.WaterDaqEditField.Value;
            app.Info.PIN.airpuff = app.AirpuffEditField.Value;
            app.Info.PIN.airpuffDaq = app.AifpuffdaqEditField.Value;
            app.Info.PIN.lickSensor = app.LickSensorInputEditField.Value; 

            %% Calibration parameters: app.Info.CalibrationParam..
            app.CalibrationParam.calibTime = str2num(app.ValveOpensEditField.Value);
            app.CalibrationParam.nRelease = str2double(app.ofdatapointsEditField.Value);

            %% Auditory stim setting
            app.Stim.samplingFreq = 48000;
            app.Stim.toneFreq = app.CC.taskparam.stimFreq*1000;
            app.Stim.fade_duration = 10;
            
            %% SERVO motor setting
            value = app.MotorDirectionSwitch.Value;
            switch value
                case 'Fwd'
                    app.Info.port.dir = 'fwd';
                    app.Info.port.approach = 0.2;
                    app.Info.port.withdraw = 0.4;
                case 'Rev'
                    app.Info.port.dir = 'rev';
                    app.Info.port.approach = 0.4;
                    app.Info.port.withdraw = 0.2;
            end

            %% DAQ settings
            app.DAQ.ARDU_baudRate = str2double(app.BaudRateHzEditField.Value);

            %% Assign water TTL at start up
            loadWaterTTL(app)
            % xlsxpath = app.Info.path.waterCalibrationRecord;
            % sheet = app.Info.session.rig;
            % 
            % % Read the data from the Excel file
            % data = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
            % 
            % % Find the column index for the field name "Fitted Slope (ml/s)"
            % fields = data.Properties.VariableNames;
            % slopeColIdx = strcmp(fields, 'Fitted Slope (ml/s)');
            % try
            %     slope = data{end, slopeColIdx};
            %     app.CalibrationParam.slope = slope;
            %     app.CC.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
            %     app.GNG.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
            %     app.LogTextArea.Value{end+1} = 'Previous calibration record found. You can SELECT THE TASK and start now!';
            %     scroll(app.LogTextArea, 'bottom');
            % catch ME
            %     confirm = msgbox('No valid slope data found. Please recalibrate.', 'Warning');
            %     uiwait(confirm);
            %     app.LogTextArea.Value{end+1} = 'No record found. You have to CALIBRATE!';
            %     scroll(app.LogTextArea, 'bottom');
            % end
        end

        % Value changed function: ControlARDUListBox
        function ControlARDUListBoxValueChanged(app, event)
            app.Info.ARDU_COM = app.ControlARDUListBox.Value;
        end

        % Button pushed function: ConnectButton_4
        function ConnectButton_4Pushed(app, event)
            if isfield(app.Info,'ARDU')                
                app.LogTextArea.Value{end+1} = [char(app.Info.ARDU_COM), ' already Connected!! For deletion, reopen the app!!'];
                scroll(app.LogTextArea, 'bottom');
            end
                app.LogTextArea.Value{end+1} =  'Assigning arduino ...... wait for connection!';
                scroll(app.LogTextArea, 'bottom');

                % use servo library
                app.Info.ARDU = arduino(app.Info.ARDU_COM, 'Uno', 'Libraries', 'Servo', 'ForceBuildOn', true);

                availableCOM = serialportlist("available");
                loadAvailableSerialports(app, 'DAQARDUListBox', availableCOM);
                app.DAQ.ARDU_COM = availableCOM(1);
                
                % write all digital pin to 0
                for i = [2:8 11]
                    pin = ['D', num2str(i)];
                    writeDigitalPin(app.Info.ARDU, pin, 0);
                end

                configurePin(app.Info.ARDU, app.Info.PIN.LED, 'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.LEDDaq, 'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.soundDaq, 'DigitalOutput');
                app.Info.servo = servo(app.Info.ARDU, app.Info.PIN.motor);
                configurePin(app.Info.ARDU, app.Info.PIN.motorDaq,'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.water, 'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.waterDaq,'DigitalOutput');
                configurePin(app.Info.ARDU,app.Info.PIN.airpuff,'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.airpuffDaq,'DigitalOutput');
                configurePin(app.Info.ARDU, app.Info.PIN.lickSensor, 'AnalogInput');

                % Move port forward
                writePosition(app.Info.servo, app.Info.port.approach); 
                app.Info.port.state = 'approach';

                tmp = char(app.Info.ARDU_COM);
                app.LogTextArea.Value{end+1} = char(['Connected to ', tmp]);
                scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: TaskListBox
        function TaskListBoxValueChanged(app, event)
            app.Info.session.taskName = app.TaskListBox.Value;
        end

        % Value changed function: CCISIsEditField
        function CCISIsEditFieldValueChanged(app, event)
            app.CC.taskparam.isi = str2double(app.CCISIsEditField.Value);
        end

        % Value changed function: CCStimdurationsEditField
        function CCStimdurationsEditFieldValueChanged(app, event)
            app.CC.taskparam.stimDur = str2double(app.CCStimdurationsEditField.Value);
        end

        % Value changed function: CCRespdurationsEditField
        function CCRespdurationsEditFieldValueChanged(app, event)
            app.CC.taskparam.respDur = str2double(app.CCRespdurationsEditField.Value);
        end

        % Value changed function: ITIsEditField
        function ITIsEditFieldValueChanged(app, event)
            value = str2num(app.ITIsEditField.Value);
            app.CC.taskparam.iti = value;
            app.GNG.taskparam.iti = value;
        end

        % Value changed function: StimFreqkHzEditField
        function StimFreqkHzEditFieldValueChanged(app, event)
            % unit is in kHz
            value = str2num(app.StimFreqkHzEditField.Value);
            app.CC.taskparam.stimFreq = value;
            app.GNG.taskparam.stimFreq = value;
            app.Stim.toneFreq = value*1000;
        end

        % Value changed function: AnimalIDEditField
        function AnimalIDEditFieldValueChanged(app, event)
            app.Info.session.animalID = app.AnimalIDEditField.Value;
        end

        % Value changed function: ExperimenterEditField
        function ExperimenterEditFieldValueChanged(app, event)
            app.Info.session.experimenter = app.ExperimenterEditField.Value;
            switch app.Info.session.experimenter
                case 'JB'
                    tmp = 'Jungwon';
                case 'ZW'
                    tmp = 'Ziyu';
                otherwise
                    tmp = [];
            end
            if isempty(tmp)
                app.LogTextArea.Value{end+1} = 'Experimenter name is invalid';
            else
                app.LogTextArea.Value{end+1} =  ['Hello ',tmp];
            end
            scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: ExptypeEditField
        function ExptypeEditFieldValueChanged(app, event)
            app.Info.session.expType = app.ExptypeEditField.Value;
        end

        % Value changed function: AnimaltypeEditField
        function AnimaltypeEditFieldValueChanged(app, event)
            app.Info.session.animalType = app.AnimaltypeEditField.Value; 
        end

        % Value changed function: SoundDaqEditField
        function SoundDaqEditFieldValueChanged(app, event)
            app.Info.PIN.soundDaq = app.SoundDaqEditField.Value;
        end

        % Value changed function: LickSensorInputEditField
        function LickSensorInputEditFieldValueChanged(app, event)
            app.Info.PIN.lickSensor = app.LickSensorInputEditField.Value;
            configurePin(app.Info.ARDU, app.Info.PIN.lickSensor, 'DigitalInput');
        end

        % Value changed function: WaterEditField
        function WaterEditFieldValueChanged(app, event)
            app.Info.PIN.water = app.WaterEditField.Value;
        end

        % Value changed function: MotorEditField
        function MotorEditFieldValueChanged(app, event)
            app.Info.PIN.motor = app.MotorEditField.Value;
            % creating a servo object: https://www.mathworks.com/help/supportpkg/arduinoio/ug/control-servo-motors.html
            app.Info.servo = servo(app.Info.ARDU, app.Info.PIN.motor);
        end

        % Value changed function: LEDEditField
        function LEDEditFieldValueChanged(app, event)
            app.Info.PIN.LED = app.LEDEditField.Value;
        end

        % Value changed function: AirpuffEditField
        function AirpuffEditFieldValueChanged(app, event)
            app.Info.PIN.airpuff = app.AirpuffEditField.Value;
        end

        % Value changed function: ValveOpensEditField
        function ValveOpensEditFieldValueChanged(app, event)
            app.CalibrationParam.calibTime = str2num(app.ValveOpensEditField.Value);
        end

        % Value changed function: ofdatapointsEditField
        function ofdatapointsEditFieldValueChanged(app, event)
            app.CalibrationParam.nRelease = str2double(app.ofdatapointsEditField.Value);
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            waterCaliRep = questdlg('Do you want to calibrate the valve?', ...
                'Water calibration','Yes','No','No'); %questdlg(quest,dlgtitle,btn1,btn2,defbtn)
            switch waterCaliRep
                case 'Yes'
                    diff_weight = calibration(app);
                    drawWeights(app, diff_weight); % calculate slope, update app.CalibrationParam.slope
                    saveWaterTTLTimeInfo(app, diff_weight); % save to xlsx
                    app.LogTextArea.Value{end+1} =  'Done calibrating. You can SELECT THE TASK and start now!';
                    scroll(app.LogTextArea, 'bottom');
                case 'No'
                    % If 'No', look for the last saved slope in the Excel file
                    xlsxpath = app.Info.path.waterCalibrationRecord;
                    sheet = app.Info.session.rig;

                    % Read the data from the Excel file
                    data = readtable(xlsxpath, 'Sheet', sheet, 'VariableNamingRule', 'preserve');
                    
                    % Find the column index for the field name "Fitted Slope (ml/s)"
                    fields = data.Properties.VariableNames;
                    slopeColIdx = strcmp(fields, 'Fitted Slope (ml/s)');
                    try
                        slope = data{end, slopeColIdx};
                        app.CalibrationParam.slope = slope;
                        app.Info.taskparam.waterTTLTime = app.CC.taskparam.waterSize / (slope*1000);
                        app.LogTextArea.Value{end+1} = 'Previous calibration record found. You can SELECT THE TASK and start now!';
                        scroll(app.LogTextArea, 'bottom');
                    catch ME
                        % Handle the case where there's an issue with 'L Fitted Slope (ml/s)'
                        confirm = msgbox('No valid slope data found. Please recalibrate.', 'Warning');
                        uiwait(confirm);
                        app.LogTextArea.Value{end+1} = 'No record found. You have to CALIBRATE!';
                        scroll(app.LogTextArea, 'bottom');
                    end
            end
        end

        % Button pushed function: PortButton
        function PortButtonPushed(app, event)
            app.PortButton.Enable = 'off';
            switch app.Info.port.state
                case 'withdraw' % withdraw -> approach
                    writePosition(app.Info.servo, app.Info.port.approach);
                    app.Info.port.state = 'approach';
                case 'approach' % approach -> withdraw
                    writePosition(app.Info.servo, app.Info.port.withdraw);
                    app.Info.port.state = 'withdraw';
            end
            % app.D.servo_angle = angle;
            app.PortButton.Enable = 'on';
        end

        % Button pushed function: WaterButton
        function WaterButtonPushed(app, event)
            % rewardTTLTime will only be updated during water calibration
            if isnumeric(app.CC.taskparam.waterTTLTime)
                app.WaterButton.Enable = 'off';
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 1);
                pause(app.CC.taskparam.waterTTLTime);
                writeDigitalPin(app.Info.ARDU, app.Info.PIN.water, 0);
                app.WaterButton.Enable = 'on';
            else
                app.LogTextArea.Value{end+1} =  '!!! Assign reward amount first by run calibration !!!';
                scroll(app.LogTextArea, 'bottom');
            end
        end

        % Value changed function: WatersizeLEditField
        function RewardsizemlEditFieldValueChanged(app, event)
            value = str2double(app.WatersizeLEditField.Value);
            app.CC.taskparam.waterSize = value;
            app.GNG.taskparam.waterSize = value;
            loadWaterTTL(app);
        end

        % Button pushed function: AirpuffButton
        function AirpuffButtonPushed(app, event)
            if app.CC.taskparam.airpuffDur == 0
                return;
            end
            writeDigitalPin(app.Info.ARDU, app.Info.PIN.airpuff, 1);
            pause(app.CC.taskparam.airpuffDur);
            writeDigitalPin(app.Info.ARDU, app.Info.PIN.airpuff, 0);
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            value = app.RunSwitch.Value; 
            switch value
                case 'Run'
                    flag = true;
                    % Check Arduino connection
                    if ~isfield(app.Info,'ARDU')
                        app.LogTextArea.Value{end+1} = '!!!!! Connect to Aruino first !!!!!';
                        
                        scroll(app.LogTextArea, 'bottom');
                        flag = false;
                    end

                    if contains(app.Info.session.taskName, 'CC')
                        app.Info.taskparam = app.CC.taskparam;
                    elseif contains(app.Info.session.taskName, 'GNG')
                        app.Info.taskparam = app.GNG.taskparam;
                    else
                        app.LogTextArea.Value{end+1} =  'Please select a CC or GNG task.';
                        scroll(app.LogTextArea, 'bottom');
                    end

                    % Check water amount assignment
                    if ~isnumeric(app.Info.taskparam.waterTTLTime)
                        app.LogTextArea.Value{end+1} =  '!!!!! Run calibration first !!!!!';
                        scroll(app.LogTextArea, 'bottom');
                        flag = false;
                    end
                    % Check task assignment
                    if ~isfield(app.Info.session,'taskName')
                        app.LogTextArea.Value{end+1} =  'Choose task type';
                        scroll(app.LogTextArea, 'bottom');
                        flag = false;
                    end

                    if flag
                        % Change Switch Lamp Color and Text
                        cla(app.bigPlot);
                        app.TaskstoppedLamp.Color = [0.39,0.83,0.07];
                        app.TaskstoppedLampLabel.Text = 'Task running';
                        app.TaskstoppedLampLabel.FontColor = [0.39,0.83,0.07];

                        trainOrRecord = questdlg('What session is this?', ...
                                     'Session type','Training','Recording','Training'); %questdlg(quest,dlgtitle,btn1,btn2,defbtn)
                        switch trainOrRecord
                            case 'Training'
                                app.Info.session.recording = 'no';
                                app.LogTextArea.Value{end+1} =  'Training session starting now!';
                                scroll(app.LogTextArea, 'bottom');
                            case 'Recording'
                                app.Info.session.recording = 'yes';
                                app.LogTextArea.Value{end+1} =  'Recording session starting now!';
                                scroll(app.LogTextArea, 'bottom');
                        end

                        % get start time
                        app.Info.session.startTime = datetime('now','Format','HH:mm:ss');
                        tmp = char(app.Info.session.startTime);
                        app.StartEditField.Value =  tmp;

                        % log
                        app.LogTextArea.Value{end+1} =  ...
                            ['Started ', app.Info.session.animalID, ' ', app.Info.session.taskName, ' at ', tmp];
                        scroll(app.LogTextArea, 'bottom');
                        disp(['Started ', app.Info.session.animalID, ' ', app.Info.session.taskName, ' at ', tmp])
                        % disp(['Created folder: ', app.Info.session.name])

                        % Port position initialization 
                        writePosition(app.Info.servo, app.Info.port.withdraw);
                        app.Info.port.state = 'withdraw';

                        if isfield(app.DAQ,'ARDU') 
                            app.LogTextArea.Value{end+1} =  'TTL recording started for this session.';
                            scroll(app.LogTextArea, 'bottom');
        
                            app.TTLstatusLamp.Color = [0.39,0.83,0.07];
                            app.TTLstatusLampLabel.Text = 'DAQ recording';
                            app.TTLstatusLampLabel.FontColor = [0.39,0.83,0.07];
                            
                            % run task: private properties, has to get feed to the function individually
                            % if contains(app.Info.session.taskName, 'CC')
                            %     app.Info.taskparam = app.CC.taskparam;
                            % elseif contains(app.Info.session.taskName, 'GNG')
                            %     app.Info.taskparam = app.GNG.taskparam;
                            % else
                            %     app.LogTextArea.Value{end+1} =  'Please select a CC or GNG task.';
                            %     scroll(app.LogTextArea, 'bottom');
                            % end
                            task.(app.Info.session.taskName)(app, app.Info, app.Stim, app.Info.ARDU, app.DAQ.ARDU); 
                            if isequal(app.RunSwitch.Value, 'Stop')
                                app.Info.session.endTime = datetime('now','Format','HH:mm:ss');
                                tmp = char(app.Info.session.endTime);
                                app.StopEditField.Value = tmp;
    
                                app.LogTextArea.Value{end+1} =  ...
                                    ['Finished ', app.Info.session.animalID, ' ',app.Info.session.taskName, ' at ', tmp];
                                scroll(app.LogTextArea, 'bottom');
    
                                % total time
                                app.Info.session.totalTime = app.Info.session.endTime - app.Info.session.startTime;
                                tmp = char(app.Info.session.totalTime);
                                app.DurationEditField.Value = tmp;
    
                                app.LogTextArea.Value{end+1} =  ...
                                    ['Total time is ', tmp];
                                scroll(app.LogTextArea, 'bottom');
    
                                app.TaskstoppedLamp.Color = [0.85,0.33,0.10];
                                app.TaskstoppedLampLabel.Text = 'Task stopped';
                                app.TaskstoppedLampLabel.FontColor = [0.85,0.33,0.10];
    
                                app.TTLstatusLamp.Color = [0.85,0.33,0.10];
                                app.TTLstatusLampLabel.Text = 'DAQ stopped';
                                app.TTLstatusLampLabel.FontColor = [0.85,0.33,0.10];
                            end
                        elseif isfield(app.DAQ, 'AD3')
                        elseif isfield(app.DAQ, 'NI')
                            % app.LogTextArea.Value{end+1} =  'TTL recording started for this session.';
                            % scroll(app.LogTextArea, 'bottom');
                            % 
                            % start(app.ADInput, "Continuous");
                            % 
                            % app.ARDURecordingTTLLamp.Color = [0.39,0.83,0.07];
                            % app.ARDURecordingTTLLamp.Text = 'TTL recorded through ARDU DAQ.';
                            % app.ARDURecordingTTLLamp.FontColor = [0.39,0.83,0.07];
                            % 
                            % % run task: private properties, has to get feed to the function individually
                            % task.(app.Info.session.taskName)(app, app.Info, app.Stim, app.Info.ARDU); 
                        else
                            app.LogTextArea.Value{end+1} =  'Trial event timing is not recorded.';
                            scroll(app.LogTextArea, 'bottom');
                        end
                    else
                        app.RunSwitch.Value = 'Stop';
                    end
                    
                case 'Stop' 
                    % get end time
                    app.Info.session.endTime = datetime('now','Format','HH:mm:ss');
                    tmp = char(app.Info.session.endTime);
                    app.StopEditField.Value = tmp;

                    app.LogTextArea.Value{end+1} =  ...
                        ['Finished ', app.Info.session.animalID, ' ',app.Info.session.taskName, ' at ', tmp];
                    scroll(app.LogTextArea, 'bottom');

                    % total time
                    app.Info.session.totalTime = app.Info.session.endTime - app.Info.session.startTime;
                    tmp = char(app.Info.session.totalTime);
                    app.DurationEditField.Value = tmp;

                    app.LogTextArea.Value{end+1} =  ...
                        ['Total time is ', tmp];
                    scroll(app.LogTextArea, 'bottom');
                    
                    app.TaskstoppedLamp.Color = [0.85,0.33,0.10];
                    app.TaskstoppedLampLabel.Text = 'Task stopped';
                    app.TaskstoppedLampLabel.FontColor = [0.85,0.33,0.10];

                    app.TTLstatusLamp.Color = [0.85,0.33,0.10];
                    app.TTLstatusLampLabel.Text = 'DAQ stopped';
                    app.TTLstatusLampLabel.FontColor = [0.85,0.33,0.10];
            end
        end

        % Button pushed function: ReSelectsavingfolderButton
        function ReSelectsavingfolderButtonPushed(app, event)
            selected_path = uigetdir('C:\', 'Please select the project folder!');
            app.Info.path.mother = selected_path;
%             selected_path   
            app.Info.path.task = fullfile(app.Info.path.mother, '+task');
%             tas = app.Info.path.task
            app.Info.path.waterCalibration = fullfile(app.Info.path.mother,'+waterCalibration');
            app.Info.path.waterCalibrationRecord = fullfile(app.Info.path.waterCalibration, 'waterCalibrationRecordEphys.xlsx');
            app.Info.path.data = fullfile(app.Info.path.mother, 'Data');
            app.Info.path.dataSession = 'undefined yet';

            task = dir(fullfile(app.Info.path.mother,'+task')); %%%%% where the task folder is defined
            task = task(contains({task.name}, {'.m'}));
            task = {task.name}; 

            for i = 1:length(task)
                task{i}(end-1:end) = [];
            end

            % display task options 
            app.TaskListBox.Items = task;

            % %% Display available serial ports for both ARDU and daq ARDU
            % availableCOM = serialportlist("available");
            % loadAvailableSerialports(app, 'ControlARDUListBox', availableCOM);
            % app.Info.ARDU_COM = availableCOM(1);
            % loadAvailableSerialports(app, 'DAQARDUListBox', availableCOM);
            % app.DAQ.ARDU_COM = availableCOM(1);
        end

        % Value changed function: InputChannelsEditField
        function InputChannelsEditFieldValueChanged(app, event)
            value = str2double(app.InputChannelsEditField.Value);
            app.Info.daq.channels = value;
        end

        % Value changed function: RigEditField
        function RigEditFieldValueChanged(app, event)
            value = app.RigEditField.Value;
            if all(isstrprop(value, 'digit'))
                app.Info.session.rig = ['rig', value];
            else
                app.Info.session.rig = value;
            end
        end

        % Value changed function: AD3daqSwtich
        function AD3daqSwtichValueChanged(app, event)
            switch app.AD3daqSwtich.Value
                case 'Record'
                    app.Info.session.daqrec = true;
                    % app.LogTextArea.Value{end+1} = 'Recording TTL!';
                    % scroll(app.LogTextArea, 'bottom');
                case 'Test'
                    app.Info.session.daqrec = false;
                    % app.LogTextArea.Value{end+1} = 'Not recording TTL!';
                    % scroll(app.LogTextArea, 'bottom');
            end
        end

        % Value changed function: AD3DAQListBox
        function AD3DAQListBoxValueChanged(app, event)
            app.Info.daq_id = app.AD3DAQListBox.Value;            
        end

        % Button pushed function: ADdaqConnectButton
        function ADdaqConnectButtonPushed(app, event)
            % app.LogTextArea.Value{end+1} = 'Assigning DAQ...... Wait for DAQ connection!';
            % scroll(app.LogTextArea, 'bottom');
            % try
            %     app.ADInput = daq("ni"); % ai, analog input             
            %     app.ADInput.Rate = str2double(app.SampleRateHzEditField.Value);
            %     channels = app.Info.daq.channels;
            %     for i = 1:length(channels)
            %         % add an input channel to a data acquisition session or
            %         % device object
            %         % addinput(daqObject, deviceID, channelID, measurementType)
            %         addinput(app.ADInput, app.Info.daq_id, string(['ai',num2str(channels(i))]), "Voltage");
            %         % For class 'daq.interfaces.DataAcquisition', Channel
            %         % is a property
            %         app.ADInput.Channels(i).Range = [-str2double(app.RangeVEditField.Value) str2double(app.RangeVEditField.Value)];
            %         channelName = ['AI',num2str(channels(i)),'EditField'];
            %         app.Info.daq.channelNames{i} = app.(channelName).Value;
            %     end
            %     app.LogTextArea.Value{end+1} = 'DAQ connected!';
            %     scroll(app.LogTextArea, 'bottom');
            % catch 
            %     app.LogTextArea.Value{end+1} = '!!!!! DAQ connection error !!!!!';
            %     scroll(app.LogTextArea, 'bottom');
            % end
        end

        % Value changed function: CCPortDelaysEditField
        function CCPortDelaysEditFieldValueChanged(app, event)
            app.CC.taskparam.portDelay = str2double(app.CCPortDelaysEditField.Value);
        end

        % Value changed function: CCUSDelaysEditField
        function CCUSDelaysEditFieldValueChanged(app, event)
            app.CC.taskparam.usDelay = str2double(app.CCUSDelaysEditField.Value);
        end

        % Value changed function: initTaskTypeSwitch
        function initTaskTypeSwitchValueChanged(app, event)
            app.Info.session.rule = app.initTaskTypeSwitch.Value;
        end

        % Button pushed function: ARDUdaqConnectButton
        function ARDUdaqConnectButtonPushed(app, event)
             if isfield(app.DAQ,'ARDU')                
                app.LogTextArea.Value{end+1} =  ['DAQ ', char(app.DAQ.ARDU_COM), ' ALREADY CONNECTED!! DELETING!!'];
                scroll(app.LogTextArea, 'bottom');

                delete(app.DAQ.ARDU);

                app.LogTextArea.Value{end+1} =  'DONE!';
                scroll(app.LogTextArea, 'bottom');
            end
                app.LogTextArea.Value{end+1} =  'Assigning DAQ arduino ... wait for connection!';
                scroll(app.LogTextArea, 'bottom');

                % connected through serial communication, not direct
                % control therefore don't need to specify library
                app.DAQ.ARDU = serialport(app.DAQ.ARDU_COM, app.DAQ.ARDU_baudRate, 'Timeout', 5); %5s
    
                tmp = char(app.DAQ.ARDU_COM);
                app.LogTextArea.Value{end+1} = char(['Connected to DAQ arduino ', tmp]);
                scroll(app.LogTextArea, 'bottom');
        end

        % Value changed function: DAQARDUListBox
        function DAQARDUListBoxValueChanged(app, event)
            app.DAQ.ARDU_COM = app.DAQARDUListBox.Value;
        end

        % Value changed function: ARDUdaqSwtich
        function ARDUdaqSwtichValueChanged(app, event)
            switch app.ARDUdaqSwtich.Value
                case 'Record'
                    app.Info.session.daqrec = true;
                case 'Test'
                    app.Info.session.daqrec = false;
            end
        end

        % Selection change function: DAQTab
        function DAQTabSelectionChanged(app, event)
          % still working on this
        end

        % Value changed function: BaudRateHzEditField
        function BaudRateHzEditFieldValueChanged(app, event)
            app.DAQ.ARDU_baudRate = str2double(app.BaudRateHzEditField.Value);
        end

        % Value changed function: LEDDaqEditField
        function LEDDaqEditFieldValueChanged(app, event)
            app.Info.PIN.LEDDaq = app.LEDDaqEditField.Value;
        end

        % Value changed function: MotorDaqEditField
        function MotorDaqEditFieldValueChanged(app, event)
            app.Info.PIN.motorDaq = app.MotorDaqEditField.Value;
        end

        % Value changed function: WaterDaqEditField
        function WaterDaqEditFieldValueChanged(app, event)
            app.Info.PIN.waterDaq = app.WaterDaqEditField.Value;
        end

        % Value changed function: AifpuffdaqEditField
        function AifpuffdaqEditFieldValueChanged(app, event)
            app.Info.PIN.airpuffDaq = app.AifpuffdaqEditField.Value;
        end

        % Value changed function: ReinforcerProbabilityEditField
        function ReinforcerProbabilityEditFieldValueChanged(app, event)
            value = app.ReinforcerProbabilityEditField.Value;
            app.CC.taskparam.USprobability = str2double(value);
            app.GNG.taskparam.reinforcerProb = str2double(value);
        end

        % Value changed function: MotorDirectionSwitch
        function MotorDirectionSwitchValueChanged(app, event)
            value = app.MotorDirectionSwitch.Value;
            switch value
                case 'Fwd'
                    app.Info.port.dir = 'fwd';
                    app.Info.port.approach = 0.2;
                    app.Info.port.withdraw = 0.4;
                case 'Rev'
                    app.Info.port.dir = 'rev';
                    app.Info.port.approach = 0.4;
                    app.Info.port.withdraw = 0.2;
            end
        end

        % Value changed function: GNGStartLEDdursEditField
        function GNGStartLEDdursEditFieldValueChanged(app, event)
            app.GNG.taskparam.startCueDur = str2double(app.GNGStartLEDdursEditField.Value);
        end

        % Value changed function: airpuffDuration
        function airpuffDurationValueChanged(app, event)
            value = str2double(app.airpuffDuration.Value);
            app.CC.taskparam.airpuffDur = value;
            app.GNG.taskparam.airpuffDur = value;
        end

        % Value changed function: CCStartLEDdursEditField
        function CCStartLEDdursEditFieldValueChanged(app, event)
            app.CC.taskparam.startCueDur = str2double(app.CCStartLEDdursEditField.Value);
        end

        % Value changed function: GNGISIsEditField
        function GNGISIsEditFieldValueChanged(app, event)
            app.GNG.taskparam.isi = str2double(app.GNGISIsEditField.Value);
        end

        % Value changed function: GNGStimdurationsEditField
        function GNGStimdurationsEditFieldValueChanged(app, event)
            app.GNG.taskparam.stimDur = str2double(app.GNGStimdurationsEditField.Value);
        end

        % Value changed function: GNGPortDelaysEditField
        function GNGPortDelaysEditFieldValueChanged(app, event)
            app.GNG.taskparam.portDelay = str2double(app.GNGPortDelaysEditField.Value);
        end

        % Value changed function: GNGLickwindowsEditField
        function GNGLickwindowsEditFieldValueChanged(app, event)
            app.GNG.taskparam.lickWindow = str2double(app.GNGLickwindowsEditField.Value);
        end

        % Value changed function: GNGReinforcerdelaysEditField
        function GNGReinforcerdelaysEditFieldValueChanged(app, event)
            app.GNG.taskparam.reinforcerDelay = str2double(app.GNGReinforcerdelaysEditField.Value);
        end

        % Value changed function: GNGRespdurationsEditField
        function GNGRespdurationsEditFieldValueChanged(app, event)
            app.GNG.taskparam.respDur = str2double(app.GNGRespdurationsEditField.Value);
        end

        % Value changed function: GNGTimeoutsEditField
        function GNGTimeoutsEditFieldValueChanged(app, event)
            app.GNG.taskparam.timeout = str2double(app.GNGTimeoutsEditField.Value);
        end

        % Value changed function: CCAirpuffProb
        function CCAirpuffProbValueChanged(app, event)
            app.CC.taskparam.airpuffProbability = str2double(app.CCAirpuffProb.Value);
        end

        % Value changed function: TotaltrialNumEditField
        function TotaltrialNumEditFieldValueChanged(app, event)
            app.Info.session.prespecifiedTrialNum = str2double(app.TotaltrialNumEditField.Value);
        end

        % Value changed function: MotorconnectedCheckBox
        function MotorconnectedCheckBoxValueChanged(app, event)
            app.Info.session.motorConnection = app.MotorconnectedCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.smallPlot = uiaxes(app.UIFigure);
            title(app.smallPlot, 'Trial Type Overview')
            xlabel(app.smallPlot, 'Trial')
            ylabel(app.smallPlot, 'CS')
            zlabel(app.smallPlot, 'Z')
            app.bigPlot = uiaxes(app.UIFigure);
            title(app.bigPlot, 'Mouse Lick Rate Plot')
            xlabel(app.bigPlot, 'Trials')
            ylabel(app.bigPlot, 'Lickrate')
            zlabel(app.bigPlot, 'Z')
            app.ConnectButton_4 = uibutton(app.UIFigure, 'push');
            app.ConnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_4Pushed, true);
            app.ConnectButton_4.Text = 'Connect';
            app.WaterButton = uibutton(app.UIFigure, 'push');
            app.WaterButton.ButtonPushedFcn = createCallbackFcn(app, @WaterButtonPushed, true);
            app.WaterButton.Text = 'Water';
            app.AirpuffButton = uibutton(app.UIFigure, 'push');
            app.AirpuffButton.ButtonPushedFcn = createCallbackFcn(app, @AirpuffButtonPushed, true);
            app.AirpuffButton.Text = 'Air puff';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial #';
            app.trialNum = uilabel(app.UIFigure);
            app.trialNum.Text = '0';
            app.RCSlicksLabel = uilabel(app.UIFigure);
            app.RCSlicksLabel.Text = 'R CS lick/s';
            app.RlickRate = uilabel(app.UIFigure);
            app.RlickRate.Text = '0';
            app.NCSlicksLabel = uilabel(app.UIFigure);
            app.NCSlicksLabel.Text = 'N CS lick/s';
            app.NlickRate = uilabel(app.UIFigure);
            app.NlickRate.Text = '0';
            app.AccuracyLabel = uilabel(app.UIFigure);
            app.AccuracyLabel.Text = 'Accuracy';
            app.BRlickRate = uilabel(app.UIFigure);
            app.BRlickRate.Text = '0';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.status = uilabel(app.UIFigure);
            app.status.Text = 'ITI';
            app.DprimeLabel = uilabel(app.UIFigure);
            app.DprimeLabel.Text = 'D-prime';
            app.BPlickRate = uilabel(app.UIFigure);
            app.BPlickRate.Text = '0';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Value = {'Good day!'};
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'text');
            app.StartEditField.Editable = 'off';
            app.StopEditFieldLabel = uilabel(app.UIFigure);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.UIFigure, 'text');
            app.StopEditField.Editable = 'off';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'text');
            app.DurationEditField.Editable = 'off';
            app.RevLearningV20ClassicalConditionMadeforAnalogDiscovery3Label = uilabel(app.UIFigure);
            app.RevLearningV20ClassicalConditionMadeforAnalogDiscovery3Label.Text = {'Neuromodulation - Behavior '; 'Single Port'};
            app.ControlARDUListBoxLabel = uilabel(app.UIFigure);
            app.ControlARDUListBoxLabel.Text = {'Control'; 'ARDU'};
            app.ControlARDUListBox = uilistbox(app.UIFigure);
            app.ControlARDUListBox.ValueChangedFcn = createCallbackFcn(app, @ControlARDUListBoxValueChanged, true);
            app.TaskListBoxLabel = uilabel(app.UIFigure);
            app.TaskListBoxLabel.Text = 'Task';
            app.TaskListBox = uilistbox(app.UIFigure);
            app.TaskListBox.ValueChangedFcn = createCallbackFcn(app, @TaskListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.sessioninfoTab = uitab(app.TabGroup);
            app.sessioninfoTab.Title = 'session info';
            app.ExperimenterEditField = uieditfield(app.sessioninfoTab, 'text');
            app.ExperimenterEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimenterEditFieldValueChanged, true);
            app.ExperimenterEditFieldLabel = uilabel(app.sessioninfoTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.initTaskTypeSwitch = uiswitch(app.sessioninfoTab, 'slider');
            app.initTaskTypeSwitch.Items = {'H_GO', 'L_GO'};
            app.initTaskTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @initTaskTypeSwitchValueChanged, true);
            app.initTaskTypeSwitch.Value = 'H_GO';
            app.AnimalIDEditFieldLabel = uilabel(app.sessioninfoTab);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.sessioninfoTab, 'text');
            app.AnimalIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalIDEditFieldValueChanged, true);
            app.AnimalIDEditField.Value = 'WA00';
            app.ExptypeEditFieldLabel = uilabel(app.sessioninfoTab);
            app.ExptypeEditFieldLabel.Text = 'Exp. type';
            app.ExptypeEditField = uieditfield(app.sessioninfoTab, 'text');
            app.ExptypeEditField.ValueChangedFcn = createCallbackFcn(app, @ExptypeEditFieldValueChanged, true);
            app.AnimaltypeEditFieldLabel = uilabel(app.sessioninfoTab);
            app.AnimaltypeEditFieldLabel.Text = 'Animal type';
            app.AnimaltypeEditField = uieditfield(app.sessioninfoTab, 'text');
            app.AnimaltypeEditField.ValueChangedFcn = createCallbackFcn(app, @AnimaltypeEditFieldValueChanged, true);
            app.RigEditFieldLabel = uilabel(app.sessioninfoTab);
            app.RigEditFieldLabel.Text = 'Rig';
            app.RigEditField = uieditfield(app.sessioninfoTab, 'text');
            app.RigEditField.ValueChangedFcn = createCallbackFcn(app, @RigEditFieldValueChanged, true);
            app.RigEditField.Value = '1';
            app.MotorDirectionSwitchLabel = uilabel(app.sessioninfoTab);
            app.MotorDirectionSwitchLabel.Text = 'Motor Direction';
            app.MotorDirectionSwitch = uiswitch(app.sessioninfoTab, 'slider');
            app.MotorDirectionSwitch.Items = {'Fwd', 'Rev'};
            app.MotorDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorDirectionSwitchValueChanged, true);
            app.MotorDirectionSwitch.Value = 'Fwd';
            app.MotorconnectedCheckBox = uicheckbox(app.sessioninfoTab);
            app.MotorconnectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotorconnectedCheckBoxValueChanged, true);
            app.MotorconnectedCheckBox.Text = ' Motor connected';
            app.MotorconnectedCheckBox.Value = true;
            app.RunSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunSwitch.Items = {'Stop', 'Run'};
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RunSwitch.Value = 'Stop';
            app.TaskstoppedLampLabel = uilabel(app.UIFigure);
            app.TaskstoppedLampLabel.Text = 'Task stopped';
            app.TaskstoppedLamp = uilamp(app.UIFigure);
            app.ReSelectsavingfolderButton = uibutton(app.UIFigure, 'push');
            app.ReSelectsavingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ReSelectsavingfolderButtonPushed, true);
            app.ReSelectsavingfolderButton.Text = {'Re/Select '; 'saving folder'};
            app.DAQTab = uitabgroup(app.UIFigure);
            app.DAQTab.SelectionChangedFcn = createCallbackFcn(app, @DAQTabSelectionChanged, true);
            app.taskparamTab = uitab(app.DAQTab);
            app.taskparamTab.Title = 'taskparam';
            app.StartLEDdursEditFieldLabel = uilabel(app.taskparamTab);
            app.StartLEDdursEditFieldLabel.Text = 'Start LED dur.(s)';
            app.CCStartLEDdursEditField = uieditfield(app.taskparamTab, 'text');
            app.CCStartLEDdursEditField.ValueChangedFcn = createCallbackFcn(app, @CCStartLEDdursEditFieldValueChanged, true);
            app.CCStartLEDdursEditField.Value = '0.5';
            app.ISIsEditFieldLabel = uilabel(app.taskparamTab);
            app.ISIsEditFieldLabel.Text = 'ISI (s)';
            app.CCISIsEditField = uieditfield(app.taskparamTab, 'text');
            app.CCISIsEditField.ValueChangedFcn = createCallbackFcn(app, @CCISIsEditFieldValueChanged, true);
            app.CCISIsEditField.Value = '0.5';
            app.StimdurationsEditFieldLabel = uilabel(app.taskparamTab);
            app.StimdurationsEditFieldLabel.Text = 'Stim duration (s)';
            app.CCStimdurationsEditField = uieditfield(app.taskparamTab, 'text');
            app.CCStimdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @CCStimdurationsEditFieldValueChanged, true);
            app.CCStimdurationsEditField.Value = '0.5';
            app.RespdurationsEditFieldLabel = uilabel(app.taskparamTab);
            app.RespdurationsEditFieldLabel.Text = 'Resp duration (s)';
            app.CCRespdurationsEditField = uieditfield(app.taskparamTab, 'text');
            app.CCRespdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @CCRespdurationsEditFieldValueChanged, true);
            app.CCRespdurationsEditField.Value = '2';
            app.ITIsEditFieldLabel = uilabel(app.taskparamTab);
            app.ITIsEditFieldLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.taskparamTab, 'text');
            app.ITIsEditField.ValueChangedFcn = createCallbackFcn(app, @ITIsEditFieldValueChanged, true);
            app.ITIsEditField.Value = '1 2';
            app.USDelaysEditFieldLabel = uilabel(app.taskparamTab);
            app.USDelaysEditFieldLabel.Text = 'US Delay (s)';
            app.CCUSDelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.CCUSDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @CCUSDelaysEditFieldValueChanged, true);
            app.CCUSDelaysEditField.Value = '1';
            app.StimFreqkHzEditFieldLabel = uilabel(app.taskparamTab);
            app.StimFreqkHzEditFieldLabel.Text = 'Stim Freq (kHz)';
            app.StimFreqkHzEditField = uieditfield(app.taskparamTab, 'text');
            app.StimFreqkHzEditField.ValueChangedFcn = createCallbackFcn(app, @StimFreqkHzEditFieldValueChanged, true);
            app.StimFreqkHzEditField.Value = '4 12';
            app.WatersizeLEditFieldLabel = uilabel(app.taskparamTab);
            app.WatersizeLEditFieldLabel.Text = 'Water size (μL)';
            app.WatersizeLEditField = uieditfield(app.taskparamTab, 'text');
            app.WatersizeLEditField.ValueChangedFcn = createCallbackFcn(app, @RewardsizemlEditFieldValueChanged, true);
            app.WatersizeLEditField.Value = '3';
            app.ReinforcerprobLabel = uilabel(app.taskparamTab);
            app.ReinforcerprobLabel.Text = 'Reinforcer prob.';
            app.ReinforcerProbabilityEditField = uieditfield(app.taskparamTab, 'text');
            app.ReinforcerProbabilityEditField.ValueChangedFcn = createCallbackFcn(app, @ReinforcerProbabilityEditFieldValueChanged, true);
            app.ReinforcerProbabilityEditField.Value = '1';
            app.PortDelaysEditFieldLabel = uilabel(app.taskparamTab);
            app.PortDelaysEditFieldLabel.Text = 'Port Delay (s)';
            app.CCPortDelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.CCPortDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @CCPortDelaysEditFieldValueChanged, true);
            app.CCPortDelaysEditField.Value = '1';
            app.ClassicalConditioningLabel = uilabel(app.taskparamTab);
            app.ClassicalConditioningLabel.Text = 'Classical Conditioning';
            app.AirpuffdurationsLabel = uilabel(app.taskparamTab);
            app.AirpuffdurationsLabel.Text = 'Airpuff duration (s)';
            app.airpuffDuration = uieditfield(app.taskparamTab, 'text');
            app.airpuffDuration.ValueChangedFcn = createCallbackFcn(app, @airpuffDurationValueChanged, true);
            app.airpuffDuration.Value = '0.2';
            app.StartLEDdursEditField_2Label = uilabel(app.taskparamTab);
            app.StartLEDdursEditField_2Label.Text = 'Start LED dur.(s)';
            app.GNGStartLEDdursEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGStartLEDdursEditField.ValueChangedFcn = createCallbackFcn(app, @GNGStartLEDdursEditFieldValueChanged, true);
            app.GNGStartLEDdursEditField.Value = '0.5';
            app.ISIsEditField_2Label = uilabel(app.taskparamTab);
            app.ISIsEditField_2Label.Text = 'ISI (s)';
            app.GNGISIsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGISIsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGISIsEditFieldValueChanged, true);
            app.GNGISIsEditField.Value = '0.5';
            app.StimdurationsEditField_2Label = uilabel(app.taskparamTab);
            app.StimdurationsEditField_2Label.Text = 'Stim duration (s)';
            app.GNGStimdurationsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGStimdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGStimdurationsEditFieldValueChanged, true);
            app.GNGStimdurationsEditField.Value = '0.5';
            app.RespdurationsEditField_2Label = uilabel(app.taskparamTab);
            app.RespdurationsEditField_2Label.Text = 'Resp duration (s)';
            app.GNGRespdurationsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGRespdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGRespdurationsEditFieldValueChanged, true);
            app.GNGRespdurationsEditField.Value = '2';
            app.PortDelaysEditField_2Label = uilabel(app.taskparamTab);
            app.PortDelaysEditField_2Label.Text = 'Port Delay (s)';
            app.GNGPortDelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGPortDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @GNGPortDelaysEditFieldValueChanged, true);
            app.GNGPortDelaysEditField.Value = '1';
            app.LickwindowsEditFieldLabel = uilabel(app.taskparamTab);
            app.LickwindowsEditFieldLabel.Text = 'Lick window (s)';
            app.GNGLickwindowsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGLickwindowsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGLickwindowsEditFieldValueChanged, true);
            app.GNGLickwindowsEditField.Value = '0.8';
            app.ReinforcerdelaysEditFieldLabel = uilabel(app.taskparamTab);
            app.ReinforcerdelaysEditFieldLabel.Text = 'Reinforcer delay (s)';
            app.GNGReinforcerdelaysEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGReinforcerdelaysEditField.ValueChangedFcn = createCallbackFcn(app, @GNGReinforcerdelaysEditFieldValueChanged, true);
            app.GNGReinforcerdelaysEditField.Value = '0.8';
            app.GONOGOLabel = uilabel(app.taskparamTab);
            app.GONOGOLabel.Text = 'GO/NOGO ';
            app.TimeoutsEditFieldLabel = uilabel(app.taskparamTab);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.GNGTimeoutsEditField = uieditfield(app.taskparamTab, 'text');
            app.GNGTimeoutsEditField.ValueChangedFcn = createCallbackFcn(app, @GNGTimeoutsEditFieldValueChanged, true);
            app.GNGTimeoutsEditField.Value = '3';
            app.AirpuffprobLabel = uilabel(app.taskparamTab);
            app.AirpuffprobLabel.Text = 'Airpuff prob.';
            app.CCAirpuffProb = uieditfield(app.taskparamTab, 'text');
            app.CCAirpuffProb.ValueChangedFcn = createCallbackFcn(app, @CCAirpuffProbValueChanged, true);
            app.CCAirpuffProb.Value = '0';
            app.TotaltrialNumEditFieldLabel = uilabel(app.taskparamTab);
            app.TotaltrialNumEditFieldLabel.Text = 'Total trial Num.';
            app.TotaltrialNumEditField = uieditfield(app.taskparamTab, 'text');
            app.TotaltrialNumEditField.ValueChangedFcn = createCallbackFcn(app, @TotaltrialNumEditFieldValueChanged, true);
            app.pinandwatercalibrationTab = uitab(app.DAQTab);
            app.pinandwatercalibrationTab.Title = 'pin and water calibration';
            app.SoundDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.SoundDaqEditFieldLabel.Text = 'Sound Daq';
            app.SoundDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.SoundDaqEditField.ValueChangedFcn = createCallbackFcn(app, @SoundDaqEditFieldValueChanged, true);
            app.SoundDaqEditField.Value = 'D4';
            app.LickSensorInputEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.LickSensorInputEditFieldLabel.Text = 'Lick Sensor Input';
            app.LickSensorInputEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.LickSensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @LickSensorInputEditFieldValueChanged, true);
            app.LickSensorInputEditField.Value = 'A1';
            app.WaterEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.WaterEditFieldLabel.Text = 'Water';
            app.WaterEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.WaterEditField.ValueChangedFcn = createCallbackFcn(app, @WaterEditFieldValueChanged, true);
            app.WaterEditField.Value = 'D7';
            app.MotorEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.MotorEditFieldLabel.Text = 'Motor';
            app.MotorEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.MotorEditField.ValueChangedFcn = createCallbackFcn(app, @MotorEditFieldValueChanged, true);
            app.MotorEditField.Value = 'D9';
            app.LEDEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.LEDEditFieldLabel.Text = 'LED';
            app.LEDEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.LEDEditField.ValueChangedFcn = createCallbackFcn(app, @LEDEditFieldValueChanged, true);
            app.LEDEditField.Value = 'D2';
            app.AirpuffEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.AirpuffEditFieldLabel.Text = 'Airpuff';
            app.AirpuffEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.AirpuffEditField.ValueChangedFcn = createCallbackFcn(app, @AirpuffEditFieldValueChanged, true);
            app.AirpuffEditField.Value = 'D5';
            app.LEDDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.LEDDaqEditFieldLabel.Text = 'LED Daq';
            app.LEDDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.LEDDaqEditField.ValueChangedFcn = createCallbackFcn(app, @LEDDaqEditFieldValueChanged, true);
            app.LEDDaqEditField.Value = 'D3';
            app.MotorDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.MotorDaqEditFieldLabel.Text = 'Motor Daq';
            app.MotorDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.MotorDaqEditField.ValueChangedFcn = createCallbackFcn(app, @MotorDaqEditFieldValueChanged, true);
            app.MotorDaqEditField.Value = 'D11';
            app.WaterDaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.WaterDaqEditFieldLabel.Text = 'Water Daq';
            app.WaterDaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.WaterDaqEditField.ValueChangedFcn = createCallbackFcn(app, @WaterDaqEditFieldValueChanged, true);
            app.WaterDaqEditField.Value = 'D8';
            app.AifpuffdaqEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.AifpuffdaqEditFieldLabel.Text = 'Aifpuff daq';
            app.AifpuffdaqEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.AifpuffdaqEditField.ValueChangedFcn = createCallbackFcn(app, @AifpuffdaqEditFieldValueChanged, true);
            app.AifpuffdaqEditField.Value = 'D6';
            app.RunCalibrationButton = uibutton(app.pinandwatercalibrationTab, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.ValveOpensEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.ValveOpensEditFieldLabel.Text = 'Valve Open (s)';
            app.ValveOpensEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.ValveOpensEditField.ValueChangedFcn = createCallbackFcn(app, @ValveOpensEditFieldValueChanged, true);
            app.ValveOpensEditField.Value = '0.1 0.2 0.5';
            app.ofdatapointsEditFieldLabel = uilabel(app.pinandwatercalibrationTab);
            app.ofdatapointsEditFieldLabel.Text = '# of datapoints';
            app.ofdatapointsEditField = uieditfield(app.pinandwatercalibrationTab, 'text');
            app.ofdatapointsEditField.ValueChangedFcn = createCallbackFcn(app, @ofdatapointsEditFieldValueChanged, true);
            app.ofdatapointsEditField.Value = '30';
            app.WatercalibrationLabel = uilabel(app.pinandwatercalibrationTab);
            app.WatercalibrationLabel.Text = 'Water calibration';
            app.ControlARDUPinInfoifchangehavetomodifyondeviceLabel = uilabel(app.pinandwatercalibrationTab);
            app.ControlARDUPinInfoifchangehavetomodifyondeviceLabel.Text = {'Control ARDU Pin Info '; '(if change have to modify on device)'};
            app.ARDUDaqInfoTab = uitab(app.DAQTab);
            app.ARDUDaqInfoTab.Title = 'ARDU Daq Info';
            app.ArduinoDigitalIOPINsifchangehavetomodifyondeviceLabel = uilabel(app.ARDUDaqInfoTab);
            app.ArduinoDigitalIOPINsifchangehavetomodifyondeviceLabel.Text = {'Arduino Digital I/O PINs '; '(if change have to modify on device)'};
            app.InputChannelsEditField_2Label = uilabel(app.ARDUDaqInfoTab);
            app.InputChannelsEditField_2Label.Text = 'Input Channels';
            app.InputChannelsEditField_2 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.InputChannelsEditField_2.Value = '2:13';
            app.BaudRateHzEditFieldLabel = uilabel(app.ARDUDaqInfoTab);
            app.BaudRateHzEditFieldLabel.Text = 'Baud Rate (Hz)';
            app.BaudRateHzEditField = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.BaudRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateHzEditFieldValueChanged, true);
            app.BaudRateHzEditField.Value = '9600';
            app.Label_7 = uilabel(app.ARDUDaqInfoTab);
            app.Label_7.Text = '8';
            app.EditField_9 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_9.Value = 'WATER';
            app.EditField_11Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_11Label.Text = '2';
            app.EditField_11 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_11.Value = 'LICK';
            app.EditField_12Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_12Label.Text = '3';
            app.EditField_12 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_12.Value = 'LED';
            app.EditField_13Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_13Label.Text = '4';
            app.EditField_13 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_13.Value = 'SOUND';
            app.EditField_14Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_14Label.Text = '5';
            app.EditField_14 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_15Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_15Label.Text = '6';
            app.EditField_15 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_15.Value = 'AIRPUFF';
            app.EditField_16Label = uilabel(app.ARDUDaqInfoTab);
            app.EditField_16Label.Text = '7';
            app.EditField_16 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_11 = uilabel(app.ARDUDaqInfoTab);
            app.Label_11.Text = '12';
            app.EditField_17 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_12 = uilabel(app.ARDUDaqInfoTab);
            app.Label_12.Text = '13';
            app.EditField_18 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_9 = uilabel(app.ARDUDaqInfoTab);
            app.Label_9.Text = '10';
            app.EditField_19 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.Label_10 = uilabel(app.ARDUDaqInfoTab);
            app.Label_10.Text = '11';
            app.EditField_20 = uieditfield(app.ARDUDaqInfoTab, 'text');
            app.EditField_20.Value = 'PORT';
            app.ARDUdaqSwtich = uiswitch(app.ARDUDaqInfoTab, 'slider');
            app.ARDUdaqSwtich.Items = {'Record', 'Test'};
            app.ARDUdaqSwtich.ValueChangedFcn = createCallbackFcn(app, @ARDUdaqSwtichValueChanged, true);
            app.ARDUdaqSwtich.Value = 'Record';
            app.AD3DaqTab = uitab(app.DAQTab);
            app.AD3DaqTab.Title = 'AD3 Daq';
            app.InputChannelsEditFieldLabel = uilabel(app.AD3DaqTab);
            app.InputChannelsEditFieldLabel.Text = 'Input Channels';
            app.InputChannelsEditField = uieditfield(app.AD3DaqTab, 'text');
            app.InputChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @InputChannelsEditFieldValueChanged, true);
            app.InputChannelsEditField.Value = '0:7';
            app.SampRateHzLabel = uilabel(app.AD3DaqTab);
            app.SampRateHzLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.AD3DaqTab, 'text');
            app.SampleRateHzEditField.Value = '20000';
            app.RangeVLabel = uilabel(app.AD3DaqTab);
            app.RangeVLabel.Text = 'Range (V)';
            app.RangeVEditField = uieditfield(app.AD3DaqTab, 'text');
            app.RangeVEditField.Value = '5';
            app.RecordingTTLLampLabel = uilabel(app.AD3DaqTab);
            app.RecordingTTLLampLabel.Text = 'Recording TTL';
            app.RecordingTTLLamp = uilamp(app.AD3DaqTab);
            app.EditFieldLabel = uilabel(app.AD3DaqTab);
            app.EditFieldLabel.Text = '0';
            app.EditField = uieditfield(app.AD3DaqTab, 'text');
            app.EditField.Value = 'LED_on';
            app.EditField_2Label = uilabel(app.AD3DaqTab);
            app.EditField_2Label.Text = '1';
            app.EditField_2 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_2.Value = 'Cue_on';
            app.EditField_3Label = uilabel(app.AD3DaqTab);
            app.EditField_3Label.Text = '2';
            app.EditField_3 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_3.Value = 'Port_on';
            app.EditField_4Label = uilabel(app.AD3DaqTab);
            app.EditField_4Label.Text = '3';
            app.EditField_4 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_4.Value = 'Lick';
            app.EditField_5Label = uilabel(app.AD3DaqTab);
            app.EditField_5Label.Text = '4';
            app.EditField_5 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_5.Value = 'Airpuff';
            app.EditField_6Label = uilabel(app.AD3DaqTab);
            app.EditField_6Label.Text = '5';
            app.EditField_6 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_6.Value = 'Water_on';
            app.EditField_7Label = uilabel(app.AD3DaqTab);
            app.EditField_7Label.Text = '6';
            app.EditField_7 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_7.Value = 'Cam_face';
            app.EditField_8Label = uilabel(app.AD3DaqTab);
            app.EditField_8Label.Text = '7';
            app.EditField_8 = uieditfield(app.AD3DaqTab, 'text');
            app.EditField_8.Value = 'Cam_tougue';
            app.AD3DigitalPINsLabel = uilabel(app.AD3DaqTab);
            app.AD3DigitalPINsLabel.Text = 'AD3 Digital PINs';
            app.AD3daqSwtich = uiswitch(app.AD3DaqTab, 'slider');
            app.AD3daqSwtich.Items = {'Test', 'Record'};
            app.AD3daqSwtich.ValueChangedFcn = createCallbackFcn(app, @AD3daqSwtichValueChanged, true);
            app.AD3daqSwtich.Value = 'Test';
            app.ADdaqConnectButton = uibutton(app.AD3DaqTab, 'push');
            app.ADdaqConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ADdaqConnectButtonPushed, true);
            app.ADdaqConnectButton.Text = 'Connect';
            app.DAQListBoxLabel = uilabel(app.AD3DaqTab);
            app.DAQListBoxLabel.Text = 'DAQ';
            app.AD3DAQListBox = uilistbox(app.AD3DaqTab);
            app.AD3DAQListBox.ValueChangedFcn = createCallbackFcn(app, @AD3DAQListBoxValueChanged, true);
            app.NIDaqTab = uitab(app.DAQTab);
            app.NIDaqTab.Title = 'NI Daq';
            app.NIDAQPINsLabel = uilabel(app.NIDaqTab);
            app.NIDAQPINsLabel.Text = 'NI-DAQ PINs';
            app.NIdaqSwtich = uiswitch(app.NIDaqTab, 'slider');
            app.NIdaqSwtich.Items = {'Test', 'Record'};
            app.NIdaqSwtich.Value = 'Test';
            app.NIdaqConnectButton = uibutton(app.NIDaqTab, 'push');
            app.NIdaqConnectButton.Text = 'Connect';
            app.InputChannelsEditField_3Label = uilabel(app.NIDaqTab);
            app.InputChannelsEditField_3Label.Text = 'Input Channels';
            app.InputChannelsEditField_3 = uieditfield(app.NIDaqTab, 'text');
            app.InputChannelsEditField_3.Value = '0:7';
            app.SampleRateHzEditField_3Label = uilabel(app.NIDaqTab);
            app.SampleRateHzEditField_3Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField_3 = uieditfield(app.NIDaqTab, 'text');
            app.SampleRateHzEditField_3.Value = '20000';
            app.RangeVEditField_3Label = uilabel(app.NIDaqTab);
            app.RangeVEditField_3Label.Text = 'Range (V)';
            app.RangeVEditField_3 = uieditfield(app.NIDaqTab, 'text');
            app.RangeVEditField_3.Value = '5';
            app.RecordingTTLLamp_3Label = uilabel(app.NIDaqTab);
            app.RecordingTTLLamp_3Label.Text = 'Recording TTL';
            app.RecordingTTLLamp_3 = uilamp(app.NIDaqTab);
            app.AI0EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI0EditFieldLabel.Text = 'AI0';
            app.AI0EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI0EditField.Value = 'Camera_tongue';
            app.AI1EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI1EditFieldLabel.Text = 'AI1';
            app.AI1EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI1EditField.Value = 'Camera_face';
            app.AI2EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI2EditFieldLabel.Text = 'AI2';
            app.AI2EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI2EditField.Value = 'Trial_start';
            app.AI3EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI3EditFieldLabel.Text = 'AI3';
            app.AI3EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI3EditField.Value = 'Lick';
            app.AI4EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI4EditFieldLabel.Text = 'AI4';
            app.AI4EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI4EditField.Value = 'Airpuff';
            app.AI5EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI5EditFieldLabel.Text = 'AI5';
            app.AI5EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI5EditField.Value = 'Port_on';
            app.AI6EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI6EditFieldLabel.Text = 'AI6';
            app.AI6EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI6EditField.Value = 'Stim';
            app.AI7EditFieldLabel = uilabel(app.NIDaqTab);
            app.AI7EditFieldLabel.Text = 'AI7';
            app.AI7EditField = uieditfield(app.NIDaqTab, 'text');
            app.AI7EditField.Value = 'Water_on';
            app.DAQListBox_3Label = uilabel(app.NIDaqTab);
            app.DAQListBox_3Label.Text = 'DAQ';
            app.NIDAQListBox = uilistbox(app.NIDaqTab);
            app.RepetitionLabel = uilabel(app.UIFigure);
            app.RepetitionLabel.Text = 'Repetition';
            app.repSwitch = uilabel(app.UIFigure);
            app.repSwitch.Text = '0';
            app.ARDUdaqConnectButton = uibutton(app.UIFigure, 'push');
            app.ARDUdaqConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ARDUdaqConnectButtonPushed, true);
            app.ARDUdaqConnectButton.Text = 'Re/Connect';
            app.ARDUDAQLabel = uilabel(app.UIFigure);
            app.ARDUDAQLabel.Text = {'DAQ'; 'ARDU'};
            app.DAQARDUListBox = uilistbox(app.UIFigure);
            app.DAQARDUListBox.ValueChangedFcn = createCallbackFcn(app, @DAQARDUListBoxValueChanged, true);
            app.TTLstatusLampLabel = uilabel(app.UIFigure);
            app.TTLstatusLampLabel.Text = 'TTL status';
            app.TTLstatusLamp = uilamp(app.UIFigure);
            app.PortButton = uibutton(app.UIFigure, 'push');
            app.PortButton.ButtonPushedFcn = createCallbackFcn(app, @PortButtonPushed, true);
            app.PortButton.Text = 'Port';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = movable_port_arduDAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end